classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConstraintsanalysisTab         matlab.ui.container.Tab
        WSConstraintEditField          matlab.ui.control.NumericEditField
        WSConstraintEditFieldLabel     matlab.ui.control.Label
        eEditField                     matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        AspectratioEditField           matlab.ui.control.NumericEditField
        AspectratioEditFieldLabel      matlab.ui.control.Label
        V_maxConstraintEditField       matlab.ui.control.NumericEditField
        V_maxConstraintEditFieldLabel  matlab.ui.control.Label
        CD_0EditField                  matlab.ui.control.NumericEditField
        CD_0EditFieldLabel             matlab.ui.control.Label
        pEditField                     matlab.ui.control.NumericEditField
        pEditFieldLabel                matlab.ui.control.Label
        V_stallConstraintEditField     matlab.ui.control.NumericEditField
        V_stallConstraintEditFieldLabel  matlab.ui.control.Label
        CL_maxInitialEditField         matlab.ui.control.NumericEditField
        CL_maxInitialEditFieldLabel    matlab.ui.control.Label
        ParametersPanel                matlab.ui.container.Panel
        GenerateButton                 matlab.ui.control.Button
        ConstraintsanalysisLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        WingandtailsizingTab           matlab.ui.container.Tab
        c_HEditField                   matlab.ui.control.NumericEditField
        c_HEditFieldLabel              matlab.ui.control.Label
        b_VEditField                   matlab.ui.control.NumericEditField
        b_VEditFieldLabel              matlab.ui.control.Label
        b_HEditField                   matlab.ui.control.NumericEditField
        b_HEditFieldLabel              matlab.ui.control.Label
        c_VEditFieldLabel              matlab.ui.control.Label
        c_VEditField                   matlab.ui.control.NumericEditField
        V_stallDesiredEditField        matlab.ui.control.NumericEditField
        V_stallDesiredEditFieldLabel   matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        SizingparametersPanel          matlab.ui.container.Panel
        V_cruiseDesiredEditField       matlab.ui.control.NumericEditField
        V_cruiseDesiredEditFieldLabel  matlab.ui.control.Label
        VerticaltailvolumecoefficientEditField  matlab.ui.control.NumericEditField
        VerticaltailvolumecoefficientEditFieldLabel  matlab.ui.control.Label
        AR_HorizontalEditField         matlab.ui.control.NumericEditField
        AR_HorizontalEditFieldLabel    matlab.ui.control.Label
        SHSWEditField                  matlab.ui.control.NumericEditField
        SHSWEditFieldLabel             matlab.ui.control.Label
        HorizontaltailvolumecoefficientEditField  matlab.ui.control.NumericEditField
        HorizontaltailvolumecoefficientEditFieldLabel  matlab.ui.control.Label
        TaperratioEditField            matlab.ui.control.NumericEditField
        TaperratioEditFieldLabel       matlab.ui.control.Label
        AR_VerticalEditField           matlab.ui.control.NumericEditField
        AR_VerticalEditFieldLabel      matlab.ui.control.Label
        GenerateButton_2               matlab.ui.control.Button
        WingsizingresultsPanel         matlab.ui.container.Panel
        MACEditField                   matlab.ui.control.NumericEditField
        MACEditFieldLabel              matlab.ui.control.Label
        ctEditField                    matlab.ui.control.NumericEditField
        ctEditFieldLabel               matlab.ui.control.Label
        crEditField                    matlab.ui.control.NumericEditField
        crEditFieldLabel               matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        SEditField                     matlab.ui.control.NumericEditField
        SEditFieldLabel                matlab.ui.control.Label
        WSEditField                    matlab.ui.control.NumericEditField
        WSEditFieldLabel               matlab.ui.control.Label
        TailsizingresultsPanel         matlab.ui.container.Panel
        TailarmEditField               matlab.ui.control.NumericEditField
        TailarmEditFieldLabel          matlab.ui.control.Label
        Re_Wing_TOEditField            matlab.ui.control.NumericEditField
        Re_Wing_TOEditFieldLabel       matlab.ui.control.Label
        Re_Wing_CrEditField            matlab.ui.control.NumericEditField
        Re_Wing_CrEditFieldLabel       matlab.ui.control.Label
        SVEditField                    matlab.ui.control.NumericEditField
        SVEditFieldLabel               matlab.ui.control.Label
        SHEditField                    matlab.ui.control.NumericEditField
        SHEditFieldLabel               matlab.ui.control.Label
        WingandtailsizingLabel         matlab.ui.control.Label
        ControlsurfacessizingTab       matlab.ui.container.Tab
        BDONELabel                     matlab.ui.control.Label
        ControlsurfacessizingLabel     matlab.ui.control.Label
        DragestimationTab              matlab.ui.container.Tab
        ViewCfvsRecurveButton          matlab.ui.control.Button
        CDivsVcurveEditField           matlab.ui.control.EditField
        CDivsVcurveLabel               matlab.ui.control.Label
        CLvsVcurveEditField            matlab.ui.control.EditField
        CLvsVcurveLabel                matlab.ui.control.Label
        ImportButton_2                 matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        SizingrelatedparametersPanel   matlab.ui.container.Panel
        WingchordwiselocationofmaxthicknessEditField  matlab.ui.control.NumericEditField
        WingchordwiselocationofmaxthicknessEditFieldLabel  matlab.ui.control.Label
        VtailthicknessEditField        matlab.ui.control.NumericEditField
        VtailthicknessEditFieldLabel   matlab.ui.control.Label
        VtailchordwiselocationofmaxthicknessEditField  matlab.ui.control.NumericEditField
        VtailchordwiselocationofmaxthicknessEditFieldLabel  matlab.ui.control.Label
        HtailchordwiselocationofmaxthicknessEditField  matlab.ui.control.NumericEditField
        HtailchordwiselocationofmaxthicknessEditFieldLabel  matlab.ui.control.Label
        HtailthicknessEditField        matlab.ui.control.NumericEditField
        HtailthicknessEditFieldLabel   matlab.ui.control.Label
        MaximumwingthicknessEditField  matlab.ui.control.NumericEditField
        MaximumwingthicknessEditFieldLabel  matlab.ui.control.Label
        GenerateCLvsCDcurveButton      matlab.ui.control.Button
        DragestimationLabel            matlab.ui.control.Label
        ImportcurvesPanel              matlab.ui.container.Panel
        OtherparametersPanel           matlab.ui.container.Panel
        InterferencefactorEditField    matlab.ui.control.NumericEditField
        InterferencefactorEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        constraintsOutput  = 0 % Description
        CL_curve; % Description
        sizing_output = 0; % Description
    end
    
    properties (Access = private)
        CDi_curve; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.constraintsOutput = constraints_analysis(app.CL_maxInitialEditField.Value,app.V_stallConstraintEditField.Value,app.pEditField.Value,app.eEditField.Value,app.CD_0EditField.Value,app.V_maxConstraintEditField.Value,app.AspectratioEditField.Value);
           app.WSConstraintEditField.Value = app.constraintsOutput(1,1);
            cla(app.UIAxes);

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        xline(app.UIAxes, app.constraintsOutput(1,1) , "g");
        hold(app.UIAxes,'on');
        plot(app.UIAxes, app.constraintsOutput(:,2) ,app.constraintsOutput(:,3) );
        legend(app.UIAxes, 'w_S_const','w_p');
        grid(app.UIAxes,'on');
        ax = app.UIAxes;
        ax.XAxisLocation = 'origin';
        ax.YAxisLocation = 'origin';
        xlabel(app.UIAxes,"W/S","Interpreter","latex");
        ylabel(app.UIAxes,"W/P","Interpreter","latex");
        hold(app.UIAxes,'off');

        end

        % Value changed function: CL_maxInitialEditField
        function CL_maxInitialEditFieldValueChanged(app, event)
            value = app.CL_maxInitialEditField.Value;
            
        end

        % Value changed function: WSConstraintEditField
        function WSConstraintEditFieldValueChanged(app, event)
            value = app.WSConstraintEditField.Value;
     
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename, ~] = uigetfile('*.csv');
            app.CLvsVcurveEditField.Value = filename;
            figure(app.UIFigure);
            app.CL_curve = readtable(filename);
            
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            [filename, ~] = uigetfile('*.csv');
            app.CDivsVcurveEditField.Value = filename;
            figure(app.UIFigure);
            app.CDi_curve = readtable(filename);
        end

        % Value changed function: CDivsVcurveEditField
        function CDivsVcurveEditFieldValueChanged(app, event)
            value = app.CDivsVcurveEditField.Value;
            
        end

        % Button pushed function: GenerateCLvsCDcurveButton
        function GenerateCLvsCDcurveButtonPushed(app, event)
         drag_calc(app.CL_curve,app.CDi_curve,app.MaximumwingthicknessEditField.Value,app.WingchordwiselocationofmaxthicknessEditField.Value,app.HtailthicknessEditField.Value,app.VtailthicknessEditField.Value,app.HtailchordwiselocationofmaxthicknessEditField.Value,app.VtailchordwiselocationofmaxthicknessEditField.Value,app.InterferencefactorEditField.Value);
        end

        % Value changed function: CLvsVcurveEditField
        function CLvsVcurveEditFieldValueChanged(app, event)
            value = app.CLvsVcurveEditField.Value;
            
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            app.sizing_output = sizing(app.MassEditField,app.V_stallDesiredEditField,app.V_cruiseDesiredEditField,app.TaperratioEditField,app.HorizontaltailvolumecoefficientEditField,app.VerticaltailvolumecoefficientEditField,app.SHSWEditField,app.AR_HorizontalEditField,app.AR_VerticalEditField);
            app.sizing_output
            app.WSEditField.Value = app.sizing_output(1);
            app.SEditField.Value = app.sizing_output(2);
            app.bEditField.Value = app.sizing_output(3);
            app.crEditField.Value = app.sizing_output(4);
            app.ctEditField.Value = app.sizing_output(5);
            app.MACEditField.Value = app.sizing_output(6);
            app.SHEditField.Value = app.sizing_output(7);
            app.SVEditField.Value = app.sizing_output(8);
            app.b_HEditField.Value = app.sizing_output(9);
            app.b_VEditField.Value = app.sizing_output(10);
            app.c_HEditField.Value = app.sizing_output(11);
            app.c_VEditField.Value = app.sizing_output(12);
            app.TailarmEditField.Value = app.sizing_output(13);
            app.Re_Wing_TOEditField.Value = app.sizing_output(14);
            app.Re_Wing_CrEditField.Value = app.sizing_output(15);
            
        end

        % Button pushed function: ViewCfvsRecurveButton
        function ViewCfvsRecurveButtonPushed(app, event)
            skin_friction();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConstraintsanalysisTab = uitab(app.TabGroup);
            app.ConstraintsanalysisTab.Title = 'Constraints analysis';
            app.UIAxes = uiaxes(app.ConstraintsanalysisTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ConstraintsanalysisLabel = uilabel(app.ConstraintsanalysisTab);
            app.ConstraintsanalysisLabel.Text = 'Constraints analysis';
            app.GenerateButton = uibutton(app.ConstraintsanalysisTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ParametersPanel = uipanel(app.ConstraintsanalysisTab);
            app.ParametersPanel.Title = 'Parameters';
            app.CL_maxInitialEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.CL_maxInitialEditFieldLabel.Text = 'CL_max (Initial)';
            app.CL_maxInitialEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.CL_maxInitialEditField.ValueChangedFcn = createCallbackFcn(app, @CL_maxInitialEditFieldValueChanged, true);
            app.V_stallConstraintEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.V_stallConstraintEditFieldLabel.Text = 'V_stall (Constraint)';
            app.V_stallConstraintEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.pEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.pEditFieldLabel.Text = 'η_p';
            app.pEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.CD_0EditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.CD_0EditFieldLabel.Text = 'CD_0';
            app.CD_0EditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.V_maxConstraintEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.V_maxConstraintEditFieldLabel.Text = 'V_max (Constraint)';
            app.V_maxConstraintEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.AspectratioEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.AspectratioEditFieldLabel.Text = 'Aspect ratio';
            app.AspectratioEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.eEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.WSConstraintEditFieldLabel = uilabel(app.ConstraintsanalysisTab);
            app.WSConstraintEditFieldLabel.Text = 'W/S (Constraint)';
            app.WSConstraintEditField = uieditfield(app.ConstraintsanalysisTab, 'numeric');
            app.WSConstraintEditField.ValueChangedFcn = createCallbackFcn(app, @WSConstraintEditFieldValueChanged, true);
            app.WSConstraintEditField.Editable = 'off';
            app.WingandtailsizingTab = uitab(app.TabGroup);
            app.WingandtailsizingTab.Title = 'Wing and tail sizing';
            app.WingandtailsizingLabel = uilabel(app.WingandtailsizingTab);
            app.WingandtailsizingLabel.Text = 'Wing and tail sizing';
            app.TailsizingresultsPanel = uipanel(app.WingandtailsizingTab);
            app.TailsizingresultsPanel.Title = 'Tail sizing results';
            app.SHEditFieldLabel = uilabel(app.TailsizingresultsPanel);
            app.SHEditFieldLabel.Text = 'SH';
            app.SHEditField = uieditfield(app.TailsizingresultsPanel, 'numeric');
            app.SHEditField.Editable = 'off';
            app.SVEditFieldLabel = uilabel(app.TailsizingresultsPanel);
            app.SVEditFieldLabel.Text = 'SV';
            app.SVEditField = uieditfield(app.TailsizingresultsPanel, 'numeric');
            app.SVEditField.Editable = 'off';
            app.Re_Wing_CrEditFieldLabel = uilabel(app.TailsizingresultsPanel);
            app.Re_Wing_CrEditFieldLabel.Text = 'Re_Wing_Cr';
            app.Re_Wing_CrEditField = uieditfield(app.TailsizingresultsPanel, 'numeric');
            app.Re_Wing_CrEditField.Editable = 'off';
            app.Re_Wing_TOEditFieldLabel = uilabel(app.TailsizingresultsPanel);
            app.Re_Wing_TOEditFieldLabel.Text = 'Re_Wing_TO';
            app.Re_Wing_TOEditField = uieditfield(app.TailsizingresultsPanel, 'numeric');
            app.Re_Wing_TOEditField.Editable = 'off';
            app.TailarmEditFieldLabel = uilabel(app.TailsizingresultsPanel);
            app.TailarmEditFieldLabel.Text = 'Tail arm';
            app.TailarmEditField = uieditfield(app.TailsizingresultsPanel, 'numeric');
            app.TailarmEditField.Editable = 'off';
            app.WingsizingresultsPanel = uipanel(app.WingandtailsizingTab);
            app.WingsizingresultsPanel.Title = 'Wing sizing results';
            app.WSEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.WSEditFieldLabel.Text = 'W/S';
            app.WSEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.WSEditField.Editable = 'off';
            app.SEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.SEditField.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.bEditField.Editable = 'off';
            app.crEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.crEditFieldLabel.Text = 'cr';
            app.crEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.crEditField.Editable = 'off';
            app.ctEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.ctEditFieldLabel.Text = 'ct';
            app.ctEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.ctEditField.Editable = 'off';
            app.MACEditFieldLabel = uilabel(app.WingsizingresultsPanel);
            app.MACEditFieldLabel.Text = 'MAC';
            app.MACEditField = uieditfield(app.WingsizingresultsPanel, 'numeric');
            app.MACEditField.Editable = 'off';
            app.GenerateButton_2 = uibutton(app.WingandtailsizingTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.SizingparametersPanel = uipanel(app.WingandtailsizingTab);
            app.SizingparametersPanel.Title = 'Sizing parameters';
            app.AR_VerticalEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.AR_VerticalEditFieldLabel.Text = 'AR_Vertical';
            app.AR_VerticalEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.TaperratioEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.TaperratioEditFieldLabel.Text = 'Taper ratio';
            app.TaperratioEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.HorizontaltailvolumecoefficientEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.HorizontaltailvolumecoefficientEditFieldLabel.Text = 'Horizontal tail volume coefficient';
            app.HorizontaltailvolumecoefficientEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.SHSWEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.SHSWEditFieldLabel.Text = 'SH/SW';
            app.SHSWEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.AR_HorizontalEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.AR_HorizontalEditFieldLabel.Text = 'AR_Horizontal';
            app.AR_HorizontalEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.VerticaltailvolumecoefficientEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.VerticaltailvolumecoefficientEditFieldLabel.Text = 'Vertical tail volume coefficient';
            app.VerticaltailvolumecoefficientEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.V_cruiseDesiredEditFieldLabel = uilabel(app.SizingparametersPanel);
            app.V_cruiseDesiredEditFieldLabel.Text = 'V_cruise (Desired)';
            app.V_cruiseDesiredEditField = uieditfield(app.SizingparametersPanel, 'numeric');
            app.MassEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.MassEditFieldLabel.Text = 'Mass ';
            app.MassEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.V_stallDesiredEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.V_stallDesiredEditFieldLabel.Text = 'V_stall (Desired)';
            app.V_stallDesiredEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.c_VEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.c_VEditField.Editable = 'off';
            app.c_VEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.c_VEditFieldLabel.Text = 'c_V';
            app.b_HEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.b_HEditFieldLabel.Text = 'b_H';
            app.b_HEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.b_HEditField.Editable = 'off';
            app.b_VEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.b_VEditFieldLabel.Text = 'b_V';
            app.b_VEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.b_VEditField.Editable = 'off';
            app.c_HEditFieldLabel = uilabel(app.WingandtailsizingTab);
            app.c_HEditFieldLabel.Text = 'c_H';
            app.c_HEditField = uieditfield(app.WingandtailsizingTab, 'numeric');
            app.c_HEditField.Editable = 'off';
            app.ControlsurfacessizingTab = uitab(app.TabGroup);
            app.ControlsurfacessizingTab.Title = 'Control surfaces sizing';
            app.ControlsurfacessizingLabel = uilabel(app.ControlsurfacessizingTab);
            app.ControlsurfacessizingLabel.Text = 'Control surfaces sizing';
            app.BDONELabel = uilabel(app.ControlsurfacessizingTab);
            app.BDONELabel.Text = '2 B DONE';
            app.DragestimationTab = uitab(app.TabGroup);
            app.DragestimationTab.Title = 'Drag estimation';
            app.OtherparametersPanel = uipanel(app.DragestimationTab);
            app.OtherparametersPanel.Title = 'Other parameters';
            app.InterferencefactorEditFieldLabel = uilabel(app.OtherparametersPanel);
            app.InterferencefactorEditFieldLabel.Text = 'Interference factor';
            app.InterferencefactorEditField = uieditfield(app.OtherparametersPanel, 'numeric');
            app.ImportcurvesPanel = uipanel(app.DragestimationTab);
            app.ImportcurvesPanel.Title = 'Import curves';
            app.DragestimationLabel = uilabel(app.DragestimationTab);
            app.DragestimationLabel.Text = 'Drag estimation';
            app.GenerateCLvsCDcurveButton = uibutton(app.DragestimationTab, 'push');
            app.GenerateCLvsCDcurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCLvsCDcurveButtonPushed, true);
            app.GenerateCLvsCDcurveButton.Text = 'Generate CL vs CD curve';
            app.SizingrelatedparametersPanel = uipanel(app.DragestimationTab);
            app.SizingrelatedparametersPanel.Title = 'Sizing related parameters';
            app.MaximumwingthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.MaximumwingthicknessEditFieldLabel.Text = 'Maximum wing thickness';
            app.MaximumwingthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.HtailthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.HtailthicknessEditFieldLabel.Text = 'H-tail thickness';
            app.HtailthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.HtailchordwiselocationofmaxthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.HtailchordwiselocationofmaxthicknessEditFieldLabel.Text = 'H-tail chordwise location of max thickness';
            app.HtailchordwiselocationofmaxthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.VtailchordwiselocationofmaxthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.VtailchordwiselocationofmaxthicknessEditFieldLabel.Text = 'V-tail chordwise location of max thickness';
            app.VtailchordwiselocationofmaxthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.VtailthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.VtailthicknessEditFieldLabel.Text = 'V-tail thickness';
            app.VtailthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.WingchordwiselocationofmaxthicknessEditFieldLabel = uilabel(app.SizingrelatedparametersPanel);
            app.WingchordwiselocationofmaxthicknessEditFieldLabel.Text = 'Wing chordwise location of max thickness';
            app.WingchordwiselocationofmaxthicknessEditField = uieditfield(app.SizingrelatedparametersPanel, 'numeric');
            app.ImportButton = uibutton(app.DragestimationTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportButton_2 = uibutton(app.DragestimationTab, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            app.CLvsVcurveLabel = uilabel(app.DragestimationTab);
            app.CLvsVcurveLabel.Text = 'CL vs V curve';
            app.CLvsVcurveEditField = uieditfield(app.DragestimationTab, 'text');
            app.CLvsVcurveEditField.ValueChangedFcn = createCallbackFcn(app, @CLvsVcurveEditFieldValueChanged, true);
            app.CLvsVcurveEditField.Editable = 'off';
            app.CDivsVcurveLabel = uilabel(app.DragestimationTab);
            app.CDivsVcurveLabel.Text = 'CDi vs V curve';
            app.CDivsVcurveEditField = uieditfield(app.DragestimationTab, 'text');
            app.CDivsVcurveEditField.ValueChangedFcn = createCallbackFcn(app, @CDivsVcurveEditFieldValueChanged, true);
            app.CDivsVcurveEditField.Editable = 'off';
            app.ViewCfvsRecurveButton = uibutton(app.DragestimationTab, 'push');
            app.ViewCfvsRecurveButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCfvsRecurveButtonPushed, true);
            app.ViewCfvsRecurveButton.Text = 'View Cf vs Re curve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end